classdef FTCscreening < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        NaNTTRButton                   matlab.ui.control.Button
        XYlimkHzdBPanel                matlab.ui.container.Panel
        DynRangeButton                 matlab.ui.control.Button
        Ymin_EditField                 matlab.ui.control.NumericEditField
        Ymax_EditField                 matlab.ui.control.NumericEditField
        Xmax_EditField                 matlab.ui.control.NumericEditField
        Xmin_EditField                 matlab.ui.control.NumericEditField
        ResetButton                    matlab.ui.control.Button
        CFThreshButtonGroup            matlab.ui.container.ButtonGroup
        DeleteButton                   matlab.ui.control.RadioButton
        ManualButton                   matlab.ui.control.RadioButton
        UpdatedButton                  matlab.ui.control.RadioButton
        OriginalButton                 matlab.ui.control.RadioButton
        STATUSTextArea                 matlab.ui.control.TextArea
        STATUSTextAreaLabel            matlab.ui.control.Label
        CLOSEButton                    matlab.ui.control.Button
        Q10globalPanel                 matlab.ui.container.Panel
        NaNButton                      matlab.ui.control.Button
        ToHighestButton                matlab.ui.control.Button
        ToLowestButton                 matlab.ui.control.Button
        ANFIdentifiersPanel            matlab.ui.container.Panel
        LoadTCButton                   matlab.ui.control.Button
        PrintButton                    matlab.ui.control.Button
        NextButton                     matlab.ui.control.Button
        PrevButton                     matlab.ui.control.Button
        UnitEditField                  matlab.ui.control.NumericEditField
        UnitEditFieldLabel             matlab.ui.control.Label
        TrackEditField                 matlab.ui.control.NumericEditField
        TrackEditFieldLabel            matlab.ui.control.Label
        ChinIDEditField                matlab.ui.control.NumericEditField
        ChinIDEditFieldLabel           matlab.ui.control.Label
        INDataDirectoryEditField       matlab.ui.control.EditField
        INDataDirectoryEditFieldLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ChinLoopProperty % Data needed to loop through all units for the current chin
        TCparams % TC parameters
        PLOTparams
        ManualClick % Description
    end
    
    methods (Access = private)
        
        function results = plotTC(app)
            TCfigName2Save= strrep(app.ChinLoopProperty.allUnitfiles(app.ChinLoopProperty.file_var).name, '.mat', '');
            app.ChinLoopProperty.FigName= [app.TCparams.TCoutputDir TCfigName2Save];
            
            if ~exist([app.ChinLoopProperty.FigName '.png'], 'file')
                app.STATUSTextAreaLabel.BackgroundColor= get_color('lr');
                app.STATUSTextArea.Value= 'Not done';
            else
                app.STATUSTextAreaLabel.BackgroundColor= get_color('lb');
                app.STATUSTextArea.Value= 'Already done';
            end
            
            results= nan;
            TCData_calib_corr_index= 4;
            
            app.PLOTparams.TFiltWidthTC=5;
            app.PLOTparams.lwData= 1.5;
            app.PLOTparams.lwIndicators=2;
            app.PLOTparams.lwLines=3;
            app.PLOTparams.fSizeLabel= 16;
            app.PLOTparams.fSizeText= 16;
            app.PLOTparams.MarkerSize= 5;
            app.PLOTparams.MarkerSize2= 12;
            app.PLOTparams.xtickVals= [.2 .5 1 2 5 10];
            
            file_var= app.ChinLoopProperty.file_var;
            All_TrackUniCombo= app.ChinLoopProperty.All_TrackUniCombo;
            
            app.TrackEditField.Value= All_TrackUniCombo(file_var, 1);
            app.UnitEditField.Value= All_TrackUniCombo(file_var, 2);
            
            curPicNum= getPicNum(app.ChinLoopProperty.allTCfiles(app.ChinLoopProperty.file_var).name);
            curCalibNum= find(curPicNum>app.ChinLoopProperty.allCalibPicNums, 1, 'last');
            if curCalibNum~=app.ChinLoopProperty.oldCalibNum
                app.ChinLoopProperty.oldCalibNum= curCalibNum;
                x_tc=load(app.ChinLoopProperty.allCalibfiles(curCalibNum).name);
                app.ChinLoopProperty.CalibData=x_tc.data.CalibData(:,1:2);
            end
            
            
            x=load(app.ChinLoopProperty.allTCfiles(app.ChinLoopProperty.file_var).name);
            x=x.data;
            TCdata=x.TcData;
            TCdata=TCdata(TCdata(:,1)~=0,:);  % Get rid of all 0 freqs
            TCdata=TCdata(TCdata(:,2)~=x.Stimuli.file_attlo,:);  % Get rid of all 'upper atten limit points'
            
            for i=1:size(TCdata,1)
                TCdata(i,3)=CalibInterp(TCdata(i,1),app.ChinLoopProperty.CalibData)-TCdata(i,2);
            end
            TCdata(:,TCData_calib_corr_index)=trifilt(TCdata(:,3)', app.PLOTparams.TFiltWidthTC)';
            app.ChinLoopProperty.TCdata= TCdata;
            
            [app.TCparams.org.thresh_dB, minInd]= min(TCdata(:,TCData_calib_corr_index));
            
            app.TCparams.org.CF_kHz= TCdata(minInd,1);
            
            
            [app.TCparams.upd.CF_kHz, app.TCparams.upd.thresh_dB]= ...
                estimate_TC_props(TCdata(:,TCData_calib_corr_index), TCdata(:,1), false);
            
            %% Calculate params for original
            [app.TCparams.org.Q10global, app.TCparams.org.frhi, app.TCparams.org.frlo_global, app.TCparams.org.Q10lev, ...
                app.TCparams.org.Q10local, app.TCparams.org.frlo_local] = ...] = ...
                findQ10_local_global(TCdata(:, 1), TCdata(:, TCData_calib_corr_index), app.TCparams.org.CF_kHz);
            app.TCparams.org.TailFreqMax= app.TCparams.org.CF_kHz/(2 ^ app.TCparams.TTRoct_belowCF);
            tail_inds_org= TCdata(:,1)<app.TCparams.org.TailFreqMax;
            if any(tail_inds_org)
                app.TCparams.org.Tail_dB= min(TCdata(tail_inds_org,4));
                app.TCparams.org.Tail_Freq= TCdata(find(TCdata(:,TCData_calib_corr_index)==app.TCparams.org.Tail_dB, 1), 1);
                app.TCparams.org.TTR_dB= app.TCparams.org.Tail_dB - app.TCparams.org.thresh_dB;
            else
                app.TCparams.org.Tail_dB= nan;
                app.TCparams.org.Tail_Freq= nan;
                app.TCparams.org.TTR_dB= nan;
            end
            
            
            %% Calculate params for updated
            [app.TCparams.upd.Q10global, app.TCparams.upd.frhi, app.TCparams.upd.frlo_global, app.TCparams.upd.Q10lev, ...
                app.TCparams.upd.Q10local, app.TCparams.upd.frlo_local] = ...
                findQ10_local_global(TCdata(:, 1), TCdata(:, TCData_calib_corr_index), app.TCparams.upd.CF_kHz);
            app.TCparams.upd.TailFreqMax= app.TCparams.upd.CF_kHz/(2^app.TCparams.TTRoct_belowCF);
            tail_inds_upd= TCdata(:,1)<app.TCparams.upd.TailFreqMax;
            if any(tail_inds_upd)
                app.TCparams.upd.Tail_dB= min(TCdata(tail_inds_upd,TCData_calib_corr_index));
                app.TCparams.upd.Tail_Freq= TCdata(find(TCdata(:,TCData_calib_corr_index)==app.TCparams.upd.Tail_dB, 1), 1);
                app.TCparams.upd.TTR_dB= app.TCparams.upd.Tail_dB - app.TCparams.upd.thresh_dB;
            else
                app.TCparams.upd.Tail_dB= nan;
                app.TCparams.upd.Tail_Freq= nan;
                app.TCparams.upd.TTR_dB= nan;
            end
            
            %% Plot
            
            app.UIAxes.cla;
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, TCdata(:,1),TCdata(:,3), 'o', 'LINEWIDTH', app.PLOTparams.lwData, ...
                'markersize', app.PLOTparams.MarkerSize, 'Color', .3*[1, 1, 1], 'Tag', 'SmoothLine');
            plot(app.UIAxes, TCdata(:,1),TCdata(:,TCData_calib_corr_index), '-', 'LINEWIDTH', app.PLOTparams.lwLines, ...
                'markersize', app.PLOTparams.MarkerSize, 'color', 'k');
            
            linHan(1)= plot(app.UIAxes, app.TCparams.org.CF_kHz, app.TCparams.org.thresh_dB,'x', 'color', get_color('g') ,...
                'markersize',app.PLOTparams.MarkerSize2, 'linew', app.PLOTparams.lwIndicators);
            linHan(2)= plot(app.UIAxes,  [app.TCparams.org.frhi, app.TCparams.org.frlo_global], ...
                [app.TCparams.org.Q10lev app.TCparams.org.Q10lev], '-.', 'color', get_color('g'), 'linew', app.PLOTparams.lwData);
            linHan(3)= plot(app.UIAxes, app.TCparams.upd.CF_kHz, app.TCparams.upd.thresh_dB,'+r' , ...
                'markersize',app.PLOTparams.MarkerSize2, 'linew', app.PLOTparams.lwIndicators);
            linHan(4)= plot(app.UIAxes, app.TCparams.org.TailFreqMax*ones(1,2), [-20 120], '-.', 'color', get_color('g'), ...
                'LineWidth', app.PLOTparams.lwData);
            linHan(5)= plot(app.UIAxes, app.TCparams.org.Tail_Freq, app.TCparams.org.Tail_dB, 'v', 'color', get_color('g'), ...
                'MarkerSize', app.PLOTparams.MarkerSize2, 'LineWidth', app.PLOTparams.lwIndicators);
            
            linHan(6)= plot(app.UIAxes,  [app.TCparams.upd.frhi, app.TCparams.upd.frlo_global], ...
                [app.TCparams.upd.Q10lev app.TCparams.upd.Q10lev],'r--', 'linew', app.PLOTparams.lwData);
            
            linHan(7)= text(app.UIAxes, max(.2, .6*app.TCparams.org.frlo_global), app.TCparams.org.Q10lev-5, ...
                sprintf('G:Q_1_0^{org}=%.1f', app.TCparams.org.Q10global), 'Color', 'g', 'FontSize', app.PLOTparams.fSizeText);
            
            
            linHan(8)= text(app.UIAxes,  max(.2, .6*app.TCparams.upd.frlo_global), app.TCparams.upd.Q10lev-15, ...
                sprintf('G:Q_1_0^{upd}=%.1f', app.TCparams.upd.Q10global), 'Color', get_color('r'), 'FontSize', app.PLOTparams.fSizeText);
            
            linHan(9)= text(app.UIAxes, app.TCparams.org.frhi, app.TCparams.org.Q10lev-5, ...
                sprintf('L:Q_1_0^{org}=%.1f', app.TCparams.org.Q10local), 'Color', 'g', 'FontSize', app.PLOTparams.fSizeText);
            
            linHan(10)= text(app.UIAxes,  app.TCparams.upd.frhi, app.TCparams.upd.Q10lev-15, ...
                sprintf('L:Q_1_0^{upd}=%.1f', app.TCparams.upd.Q10global), 'Color', get_color('r'), 'FontSize', app.PLOTparams.fSizeText);
            
            linHan(11)= plot(app.UIAxes, app.TCparams.upd.TailFreqMax*ones(1,2), [-20 120], '--', 'color', get_color('r'), ...
                'LineWidth', app.PLOTparams.lwData);
            linHan(12)= plot(app.UIAxes, app.TCparams.upd.Tail_Freq, app.TCparams.upd.Tail_dB, '^', 'color', get_color('r'), ...
                'MarkerSize', app.PLOTparams.MarkerSize2, 'LineWidth', app.PLOTparams.lwIndicators);
            
            app.TCparams.TextHan= text(app.UIAxes, .035, .05, sprintf( ...
                'CF = %.1f kHz | Thresh= %.1f dB SPL | Q_1_0^{global}= %.1f | Q_1_0^{local}= %.1f | TTR = %.1f dB', ...
                app.TCparams.org.CF_kHz, app.TCparams.org.thresh_dB, app.TCparams.org.Q10global, app.TCparams.org.Q10local, ...
                app.TCparams.org.TTR_dB), 'FontSize', app.PLOTparams.fSizeText, 'Units', 'normalized');
            
            
            ResetButtonPushed(app); % To set XLIM adn YLIM
            
            app.Xmin_EditField.Value= app.UIAxes.XLim(1);
            app.Xmax_EditField.Value= app.UIAxes.XLim(2);
            app.Ymax_EditField.Value= app.UIAxes.YLim(2);
            app.Ymin_EditField.Value= app.UIAxes.YLim(1);
            
            app.UIAxes.FontSize= app.PLOTparams.fSizeLabel;
            app.UIAxes.XTick= app.PLOTparams.xtickVals;
            
            app.UIAxes.Title.String= [[app.INDataDirectoryEditField.Value '  |  '] ...
                app.ChinLoopProperty.allTCfiles(file_var).name(1:end-4)];
            app.UIAxes.Title.Interpreter= 'none';
            app.UIAxes.Visible= 'on';
            app.UIAxes.XLabel.String= 'Frequency (kHz)';
            app.UIAxes.YLabel.String= 'Threshold (dB SPL)';
            app.UIAxes.XScale= 'log';
            pan(app.UIAxes,'on');
            zoom(app.UIAxes,'on');
            
            app.TCparams.LineHan= linHan;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ChinLoopProperty.CodesDir='/media/parida/DATAPART1/Matlab/Screening/';
            addpath(app.ChinLoopProperty.CodesDir);
            app.TCparams.TTRoct_belowCF= 1.5;
            
        end

        % Button pushed function: PrevButton
        function PrevButtonPushed(app, event)
            app.CFThreshButtonGroup.Buttons(1).Value= 1;
            app.ChinLoopProperty.file_var= max(1, app.ChinLoopProperty.file_var - 1);
            plotTC(app);
            
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.CFThreshButtonGroup.Buttons(1).Value= 1;
            if app.ChinLoopProperty.file_var < size(app.ChinLoopProperty.All_TrackUniCombo, 1)
                app.ChinLoopProperty.file_var= app.ChinLoopProperty.file_var + 1;
                plotTC(app);
            else
                app.STATUSTextArea.Value= sprintf('All units done for Q%.0f', app.ChinIDEditField.Value);
                app.STATUSTextAreaLabel.BackgroundColor= get_color('g');
            end
        end

        % Value changed function: ChinIDEditField
        function ChinIDEditFieldValueChanged(app, event)
            ChinNum = app.ChinIDEditField.Value;
            MATDataDir='/media/parida/DATAPART1/Matlab/ExpData/MatData/';
            TCRootDir= '/media/parida/DATAPART1/Matlab/GeneralOutput/TCoutput/';
            
            checkDIR=dir(sprintf('%s*Q%d*AN*',MATDataDir,ChinNum));
            if isempty(checkDIR)
                error('No such directory for animal number %d with the string ''AN''',ChinNum);
            elseif length(checkDIR)~=1
                
                fprintf('Multiple directories found.\n');
                for dirVar= 1:length(checkDIR)
                    fprintf('(%d)-%s\n', dirVar, checkDIR(dirVar).name);
                end
                
                chosen_dir_num= input('Which one? \n');
                DataDir=[MATDataDir checkDIR(chosen_dir_num).name];
                
                %     error('Multiple directories. Change!');
                
            else
                DataDir=[MATDataDir checkDIR.name];
            end
            
            app.TCparams.TCoutputDir= [TCRootDir checkDIR.name filesep];
            if ~isfolder(app.TCparams.TCoutputDir)
                mkdir(app.TCparams.TCoutputDir);
            end
            
            cd(DataDir);
            ind_filesep= max(strfind(DataDir, filesep));
            chinDirStr= DataDir(ind_filesep+1:end);
            app.INDataDirectoryEditField.Value= chinDirStr;
            
            allCalibfiles=dir('p*calib*raw*');
            if isempty(allCalibfiles)
                allCalibfiles=dir('p*calib*');
            end
            
            allCalibPicNums= cellfun(@(x) getPicNum(x), {allCalibfiles.name}');
            % fprintf('Using file : %s as calib files\n', allCalibfiles(end).name);
            app.ChinLoopProperty.oldCalibNum= allCalibPicNums(1);
            x_calib=load(allCalibfiles(allCalibPicNums(1)).name);
            app.ChinLoopProperty.CalibData= x_calib.data.CalibData(:,1:2);
            app.ChinLoopProperty.allCalibfiles= allCalibfiles;
            
            allTCfiles=dir('*tc*');
            allUnitfiles=dir('Unit*');
            
            if length(allUnitfiles)>length(allTCfiles)
                cd(app.ChinLoopProperty.CodesDir);
                error('There are %d TC files, but %d Unit files. Delete the unit files that are extra.', ...
                    length(allTCfiles), length(allUnitfiles));
            elseif length(allUnitfiles)<length(allTCfiles)
                cd(app.ChinLoopProperty.CodesDir);
                error('There are %d TC files, but %d Unit files. Delete extra TC or move it to a different folder', ...
                    length(allTCfiles), length(allUnitfiles));
            end
            
            % Assign all variables that need to be shared and used in plot
            app.ChinLoopProperty.All_TrackUniCombo= ...
                cell2mat(cellfun(@(x) sscanf(x, 'Unit_%f_%02f.mat')', {allUnitfiles.name}', 'UniformOutput', false));
            app.ChinLoopProperty.file_var= 1;
            app.ChinLoopProperty.allTCfiles= allTCfiles;
            app.ChinLoopProperty.allUnitfiles= allUnitfiles;
            app.ChinLoopProperty.allCalibPicNums= allCalibPicNums;
            
            % Note: TTR = tip-to-tail ratio
            app.TCparams.org= struct('CF_kHz', nan, 'Q10global', nan, 'thresh_dB', nan, 'TTR', nan, 'frlo_global', nan, 'frhi', nan, 'Q10lev', nan);
            app.TCparams.man= struct('CF_kHz', nan, 'Q10global', nan, 'thresh_dB', nan, 'TTR', nan, 'frlo_global', nan, 'frhi', nan, 'Q10lev', nan);
            app.TCparams.man= struct('CF_kHz', nan, 'Q10global', nan, 'thresh_dB', nan, 'TTR', nan, 'frlo_global', nan, 'frhi', nan, 'Q10lev', nan);
            
            app.ManualClick.PointXY= nan;
            app.ManualClick.LineHan= [];
            app.ManualClick.ReloadFlag= 0;
            
            
            plotTC(app);
            
        end

        % Button pushed function: PrintButton
        function PrintButtonPushed(app, event)
            
            % First load data
            allUnitfiles= app.ChinLoopProperty.allUnitfiles;
            x=load(allUnitfiles(app.ChinLoopProperty.file_var).name);
            data=x.data;
            
            ResetButtonPushed(app);
            delete(app.TCparams.LineHan);
            
            % Update fields
            switch app.CFThreshButtonGroup.SelectedObject.Text
                
                case {'Original'}
                    data.BFmod= app.TCparams.org.CF_kHz;
                    data.Thresh_dB_mod= app.TCparams.org.thresh_dB;
                    data.Q10_global_mod= app.TCparams.org.Q10global;
                    data.Q10_local_mod= app.TCparams.org.Q10local;
                    data.TTR_dB_mod= app.TCparams.org.TTR_dB;
                    data.frlo_global_mod= app.TCparams.org.frlo_global;
                    data.frlo_local_mod= app.TCparams.org.frlo_local;
                    data.frhi_mod= app.TCparams.org.frhi;
                    data.Tail_Freq_mod= app.TCparams.org.Tail_Freq;
                    data.Tail_dB_mod= app.TCparams.org.Tail_dB;
                    
                case {'Updated'}
                    data.BFmod= app.TCparams.upd.CF_kHz;
                    data.Thresh_dB_mod= app.TCparams.upd.thresh_dB;
                    data.Q10_global_mod= app.TCparams.upd.Q10global;
                    data.Q10_local_mod= app.TCparams.upd.Q10local;
                    data.TTR_dB_mod= app.TCparams.upd.TTR_dB;
                    data.frlo_global_mod= app.TCparams.upd.frlo_global;
                    data.frlo_local_mod= app.TCparams.upd.frlo_local;
                    data.frhi_mod= app.TCparams.upd.frhi;
                    data.Tail_Freq_mod= app.TCparams.upd.Tail_Freq;
                    data.Tail_dB_mod= app.TCparams.upd.Tail_dB;
                    
                case {'Manual'}
                    data.BFmod= app.TCparams.man.CF_kHz;
                    data.Thresh_dB_mod= app.TCparams.man.thresh_dB;
                    data.Q10_global_mod= app.TCparams.man.Q10global;
                    data.Q10_local_mod= app.TCparams.man.Q10local;
                    data.TTR_dB_mod= app.TCparams.man.TTR_dB;
                    data.frlo_global_mod= app.TCparams.man.frlo_global;
                    data.frlo_local_mod= app.TCparams.man.frlo_local;
                    data.frhi_mod= app.TCparams.man.frhi;
                    data.Tail_Freq_mod= app.TCparams.man.Tail_Freq;
                    data.Tail_dB_mod= app.TCparams.man.Tail_dB;
                    
                case {'Delete'}
                    data.BFmod= nan;
                    data.Thresh_dB_mod= nan;
                    data.Q10_global_mod= nan;
                    data.Q10_local_mod= nan;
                    data.TTR_dB_mod= nan;
                    data.frlo_global_mod= nan;
                    data.frlo_local_mod= nan;
                    data.frhi_mod= nan;
                    data.Tail_Freq_mod= nan;
                    data.Tail_dB_mod= nan;
                    
            end
            
            % Save updated data
            save(allUnitfiles(app.ChinLoopProperty.file_var).name,'data');
            
            % Print/Save figure
            
%             app.TCparams.TextHan.String= sprintf('CF = %.1f kHz | Thresh= %.1f dB SPL | Q_1_0= %.1f | TTR = %.1f dB', ...
%                 data.BFmod, data.Thresh_dB_mod, data.Q10_global_mod, data.TTR_dB_mod);
            
            app.TCparams.TextHan.String= ...
                sprintf('CF = %.1f kHz | Thresh= %.1f dB SPL | Q_1_0^{global}= %.1f | Q_1_0^{local}= %.1f | TTR = %.1f dB', ...
                data.BFmod, data.Thresh_dB_mod, data.Q10_global_mod, data.Q10_local_mod, data.TTR_dB_mod);
            
            
            plot(app.UIAxes, data.BFmod, data.Thresh_dB_mod,'rx' ,'markersize',app.PLOTparams.MarkerSize2, ...
                'linew', app.PLOTparams.lwIndicators);
            plot(app.UIAxes,  [data.frhi_mod, data.frlo_global_mod], [data.Thresh_dB_mod data.Thresh_dB_mod]+10,'r-.', ...
                'linew', app.PLOTparams.lwData);
            plot(app.UIAxes,  [data.frlo_global_mod data.frlo_global_mod], [data.Thresh_dB_mod+10 data.Thresh_dB_mod+5],'g:', ...
                'linew', app.PLOTparams.lwData);
            plot(app.UIAxes,  [data.frlo_local_mod data.frlo_local_mod], [data.Thresh_dB_mod+10 data.Thresh_dB_mod+15],'b:', ...
                'linew', app.PLOTparams.lwData);
            plot(app.UIAxes, data.BFmod/(2 ^ app.TCparams.TTRoct_belowCF)*ones(1,2), [-20 120], '-.', 'color', get_color('r'));
            plot(app.UIAxes, data.Tail_Freq_mod, data.Tail_dB_mod, '+', 'color', get_color('r'), ...
                'MarkerSize', app.PLOTparams.MarkerSize2, 'LineWidth', app.PLOTparams.lwIndicators);


            %% Workaround because Matlab 2018 won't let us save a figure
            hanTC= copyUIAxes(app.UIAxes);
            set (hanTC.figure, 'Units', 'normalized', 'Position', [.1 .1 .7 .6]);
            xtickVals= [.2 .5 1 2 5 10];
            set(hanTC.axes, 'Position', [.06 .1 .9 .85], 'xtick', xtickVals);
            set(findall(hanTC.figure,'-property','FontSize'), 'FontSize',  18);
            drawnow;
            saveas(hanTC.figure, app.ChinLoopProperty.FigName, 'png');
            close(hanTC.figure);
            app.STATUSTextAreaLabel.BackgroundColor= get_color('lb');
            app.STATUSTextArea.Value= 'Already done';
            
            
        end

        % Button pushed function: CLOSEButton
        function CLOSEButtonPushed(app, event)
            cd(app.ChinLoopProperty.CodesDir);
            rmpath(app.ChinLoopProperty.CodesDir);
            app.delete;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            TCdata= app.ChinLoopProperty.TCdata;
            app.UIAxes.XLim= [.2 max(11, 1.05*max(TCdata(:,1)))];
            app.UIAxes.YLim= [-20 120];
            app.Xmin_EditField.Value= app.UIAxes.XLim(1);
            app.Xmax_EditField.Value= app.UIAxes.XLim(2);
            app.Ymax_EditField.Value= app.UIAxes.YLim(2);
            app.Ymin_EditField.Value= app.UIAxes.YLim(1);
            app.UIAxes.XTick= app.PLOTparams.xtickVals;
        end

        % Button pushed function: LoadTCButton
        function LoadTCButtonPushed(app, event)
            
            file_var= find(ismember(app.ChinLoopProperty.All_TrackUniCombo, ...
                [app.TrackEditField.Value, app.UnitEditField.Value], 'rows'));
            if ~isempty(file_var)
                app.ChinLoopProperty.file_var= file_var;
            else
                app.STATUSTextArea.Value= sprintf('Error: No Unit_%.0f_%02d file for Q%.0f', ...
                    app.TrackEditField, app.UnitEditField, app.ChinIDEditField.Value);
                app.STATUSTextAreaLabel.BackgroundColor= [1 0 0];
                app.STATUSTextArea.FontSize= 12;
            end
            app.CFThreshButtonGroup.Buttons(1).Value= 1;
            plotTC(app);
            
        end

        % Selection changed function: CFThreshButtonGroup
        function CFThreshButtonGroupSelectionChanged(app, event)
            TCdata= app.ChinLoopProperty.TCdata;
            TCData_calib_corr_index= 4;
            TCData_freq_index= 1;
            
            switch app.CFThreshButtonGroup.SelectedObject.Text
                case {'Original'}
%                     app.TCparams.TextHan.String= sprintf('CF = %.1f kHz | Thresh= %.1f dB SPL | Q_1_0= %.1f | TTR = %.1f dB', ...
%                         app.TCparams.org.CF_kHz, app.TCparams.org.thresh_dB, app.TCparams.org.Q10global, app.TCparams.org.TTR_dB);
                    app.TCparams.TextHan.String= sprintf( ...
                        'CF = %.1f kHz | Thresh= %.1f dB SPL | Q_1_0^{global}= %.1f | Q_1_0^{local}= %.1f | TTR = %.1f dB', ...
                        app.TCparams.org.CF_kHz, app.TCparams.org.thresh_dB, app.TCparams.org.Q10global, app.TCparams.org.Q10local, ...
                        app.TCparams.org.TTR_dB);
                    
                    if app.ManualClick.ReloadFlag
                        plotTC(app);
                        app.ManualClick.ReloadFlag= 0;
                    end
                    
                    
                case {'Updated'}
                    app.TCparams.TextHan.String= sprintf( ...
                        'CF = %.1f kHz | Thresh= %.1f dB SPL | Q_1_0^{global}= %.1f | Q_1_0^{local}= %.1f | TTR = %.1f dB', ...
                        app.TCparams.upd.CF_kHz, app.TCparams.upd.thresh_dB, app.TCparams.upd.Q10global, app.TCparams.upd.Q10local, ...
                        app.TCparams.upd.TTR_dB);
                    if app.ManualClick.ReloadFlag
                        plotTC(app);
                        app.ManualClick.ReloadFlag= 0;
                    end
                    
                case {'Manual'}
                    if ~isnan(app.ManualClick.PointXY)
                        
                        app.ManualClick.ReloadFlag= 1;
                        ind= dsearchn(TCdata(:, TCData_freq_index), app.ManualClick.PointXY);
                        delete(app.TCparams.LineHan);
                        
                        app.TCparams.man.CF_kHz= TCdata(ind, TCData_freq_index);
                        app.TCparams.man.thresh_dB= TCdata(ind,TCData_calib_corr_index);
                        [app.TCparams.man.Q10global, app.TCparams.man.frhi, app.TCparams.man.frlo_global, app.TCparams.man.Q10lev, ...
                            app.TCparams.man.Q10local, app.TCparams.man.frlo_local] = ...
                            findQ10_local_global(TCdata(:, TCData_freq_index), TCdata(:, TCData_calib_corr_index), TCdata(ind,1));
                        app.TCparams.man.TailFreqMax= app.TCparams.man.CF_kHz/(2 ^ app.TCparams.TTRoct_belowCF);
                        tail_inds_man= TCdata(:,1)<app.TCparams.man.TailFreqMax;
                        if any(tail_inds_man)
                            app.TCparams.man.Tail_dB= min(TCdata(tail_inds_man,4));
                            app.TCparams.man.Tail_Freq= TCdata(find(TCdata(:,TCData_calib_corr_index)==app.TCparams.man.Tail_dB, 1), 1);
                            app.TCparams.man.TTR_dB= app.TCparams.man.Tail_dB - app.TCparams.man.thresh_dB;
                        else
                            app.TCparams.man.Tail_dB= nan;
                            app.TCparams.man.Tail_Freq= nan;
                            app.TCparams.man.TTR_dB= nan;
                        end


                        linHan(1)= plot(app.UIAxes, TCdata(ind,TCData_freq_index),app.TCparams.man.thresh_dB, 'rx', ...
                            'LINEWIDTH',app.PLOTparams.lwLines, 'markersize', app.PLOTparams.MarkerSize2);
                        linHan(2)= plot(app.UIAxes,  [app.TCparams.man.frhi, app.TCparams.man.frlo_global], ...
                            [app.TCparams.man.Q10lev app.TCparams.man.Q10lev],'r--', 'linew', app.PLOTparams.lwData);
                        linHan(3)= text(app.UIAxes, max(.2, .6*app.TCparams.man.frlo_global), app.TCparams.man.Q10lev-5, ...
                            sprintf('G:Q_1_0^{man}=%.1f', app.TCparams.man.Q10global), 'Color', 'c', 'FontSize', app.PLOTparams.fSizeText);
                        linHan(4)= text(app.UIAxes, app.TCparams.man.frhi, app.TCparams.man.Q10lev-15, ...
                            sprintf('L:Q_1_0^{man}=%.1f', app.TCparams.man.Q10local), 'Color', 'c', 'FontSize', app.PLOTparams.fSizeText);
                        linHan(5)= plot(app.UIAxes, app.TCparams.man.TailFreqMax*ones(1,2), [-20 120], '-.', 'color', get_color('g'));
                        linHan(6)= plot(app.UIAxes, app.TCparams.man.Tail_Freq, app.TCparams.man.Tail_dB, 'v', 'color', get_color('g'), ...
                            'MarkerSize', app.PLOTparams.MarkerSize2);
                        
                        app.TCparams.LineHan= linHan;
                        
                    app.TCparams.TextHan.String= sprintf( ...
                        'CF = %.1f kHz | Thresh= %.1f dB SPL | Q_1_0^{global}= %.1f | Q_1_0^{local}= %.1f | TTR = %.1f dB', ...
                        app.TCparams.man.CF_kHz, app.TCparams.man.thresh_dB, app.TCparams.man.Q10global, app.TCparams.man.Q10local, ...
                        app.TCparams.man.TTR_dB);
                    else
                        app.STATUSTextAreaLabel.BackgroundColor= get_color('r');
                        app.STATUSTextArea.Value= 'First click and choose a data point';
                    end
                    
                case {'Delete'}
                    app.TCparams.TextHan.String= sprintf( ...
                        'CF = %.1f kHz | Thresh= %.1f dB SPL | Q_1_0^{global}= %.1f | Q_1_0^{local}= %.1f | TTR = %.1f dB', ...
                        nan, nan, nan, nan, nan);
                    delete(app.TCparams.LineHan);
                    app.ManualClick.ReloadFlag= 1;
            end
            
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            TCdata= app.ChinLoopProperty.TCdata;
            if ~isempty(event.Source.CurrentObject) && isequal(event.Source.CurrentObject.Tag,'SmoothLine')
                AxisPosition= app.UIAxes.InnerPosition;
                CurrentPointX= event.Source.CurrentPoint(1);
                AxisXLIM= app.UIAxes.XLim;
                
                if strcmp(app.UIAxes.XScale, 'log')
                    AxisXLIM= log10(AxisXLIM);
                end
                
                Xvalue= min(AxisXLIM) + range(AxisXLIM )*(CurrentPointX-AxisPosition(1)) / AxisPosition(3);
                
                if strcmp(app.UIAxes.XScale, 'log')
                    Xvalue= 10 ^ Xvalue;
                end
                app.ManualClick.PointXY= Xvalue;
                ind= dsearchn(TCdata(:, 1), app.ManualClick.PointXY);
                if ~isempty(app.ManualClick.LineHan)
                    delete(app.ManualClick.LineHan);
                end
                app.ManualClick.LineHan= plot(app.UIAxes, TCdata(ind, 1), TCdata(ind, 3), 'bd', ...
                    'LineWidth', app.PLOTparams.lwIndicators, 'MarkerSize', app.PLOTparams.MarkerSize2);
                
            elseif strcmp(app.UIFigure.SelectionType,'normal')
                zoom(app.UIAxes,'on');
            end
            
            
        end

        % Value changed function: Xmin_EditField
        function Xmin_EditFieldValueChanged(app, event)
            value = app.Xmin_EditField.Value;
            app.UIAxes.XLim= [value max(app.UIAxes.XLim)];
            app.UIAxes.XTickMode= 'Auto';
        end

        % Value changed function: Xmax_EditField
        function Xmax_EditFieldValueChanged(app, event)
            value = app.Xmax_EditField.Value;
            app.UIAxes.XLim= [min(app.UIAxes.XLim) value];
            app.UIAxes.XTickMode= 'Auto';
            
        end

        % Value changed function: Ymax_EditField
        function Ymax_EditFieldValueChanged(app, event)
            value = app.Ymax_EditField.Value;
            app.UIAxes.YLim= [min(app.UIAxes.YLim) value];
            app.UIAxes.XTickMode= 'Auto';
        end

        % Value changed function: Ymin_EditField
        function Ymin_EditFieldValueChanged(app, event)
            value = app.Ymin_EditField.Value;
            app.UIAxes.YLim= [value max(app.UIAxes.YLim)];
            app.UIAxes.XTickMode= 'Auto';
        end

        % Button pushed function: DynRangeButton
        function DynRangeButtonPushed(app, event)
            freq_index= 1;
            data_index= 3;
            TCdata= app.ChinLoopProperty.TCdata;
            app.UIAxes.XLim= [min(TCdata(:,freq_index))*0.9 max(TCdata(:,freq_index))*1.05];
            app.UIAxes.YLim= [min(TCdata(:,data_index))-2 max(TCdata(:,data_index))+2];
            app.Xmin_EditField.Value= app.UIAxes.XLim(1);
            app.Xmax_EditField.Value= app.UIAxes.XLim(2);
            app.Ymax_EditField.Value= app.UIAxes.YLim(2);
            app.Ymin_EditField.Value= app.UIAxes.YLim(1);
            app.UIAxes.XTickMode= 'Auto';
            
        end

        % Button pushed function: ToLowestButton
        function ToLowestButtonPushed(app, event)
            TCdata= app.ChinLoopProperty.TCdata;
            
            switch app.CFThreshButtonGroup.SelectedObject.Text
                
                case {'Original'}
                    app.TCparams.org.frlo_global= min(TCdata(:,1));
                    app.TCparams.org.Q10global= app.TCparams.org.CF_kHz/(app.TCparams.org.frhi-app.TCparams.org.frlo_global);
                    app.TCparams.TextHan.String= sprintf( ...
                        'CF = %.1f kHz | Thresh= %.1f dB SPL | Q_1_0^{global}= %.1f | Q_1_0^{local}= %.1f | TTR = %.1f dB', ...
                        app.TCparams.org.CF_kHz, app.TCparams.org.thresh_dB, app.TCparams.org.Q10global, app.TCparams.org.Q10local, ...
                        app.TCparams.org.TTR_dB);
                    
                case {'Updated'}
                    app.TCparams.upd.frlo_global= min(TCdata(:,1));
                    app.TCparams.upd.Q10global= app.TCparams.upd.CF_kHz/(app.TCparams.upd.frhi-app.TCparams.upd.frlo_global);
                    app.TCparams.TextHan.String= sprintf( ...
                        'CF = %.1f kHz | Thresh= %.1f dB SPL | Q_1_0^{global}= %.1f | Q_1_0^{local}= %.1f | TTR = %.1f dB', ...
                        app.TCparams.upd.CF_kHz, app.TCparams.upd.thresh_dB, app.TCparams.upd.Q10global, app.TCparams.upd.Q10local, ...
                        app.TCparams.upd.TTR_dB);
                    
                case {'Manual'}
                    app.TCparams.man.frlo_global= min(TCdata(:,1));
                    app.TCparams.man.Q10global= app.TCparams.man.CF_kHz/(app.TCparams.man.frhi-app.TCparams.man.frlo_global);
                    app.TCparams.TextHan.String= sprintf( ...
                        'CF = %.1f kHz | Thresh= %.1f dB SPL | Q_1_0^{global}= %.1f | Q_1_0^{local}= %.1f | TTR = %.1f dB', ...
                        app.TCparams.man.CF_kHz, app.TCparams.man.thresh_dB, app.TCparams.man.Q10global, app.TCparams.man.Q10local, ...
                        app.TCparams.man.TTR_dB);
                    
            end
            
            
        end

        % Button pushed function: ToHighestButton
        function ToHighestButtonPushed(app, event)
            TCdata= app.ChinLoopProperty.TCdata;
            
            switch app.CFThreshButtonGroup.SelectedObject.Text
                
                case {'Original'}
                    app.TCparams.org.frhi= max(TCdata(:,1));
                    app.TCparams.org.Q10global= app.TCparams.org.CF_kHz/(app.TCparams.org.frhi-app.TCparams.org.frlo_global);
                    app.TCparams.TextHan.String= sprintf( ...
                        'CF = %.1f kHz | Thresh= %.1f dB SPL | Q_1_0^{global}= %.1f | Q_1_0^{local}= %.1f | TTR = %.1f dB', ...
                        app.TCparams.org.CF_kHz, app.TCparams.org.thresh_dB, app.TCparams.org.Q10global, app.TCparams.org.Q10local, ...
                        app.TCparams.org.TTR_dB);
                    
                case {'Updated'}
                    app.TCparams.upd.frhi= max(TCdata(:,1));
                    app.TCparams.upd.Q10global= app.TCparams.upd.CF_kHz/(app.TCparams.upd.frhi-app.TCparams.upd.frlo_global);
                    app.TCparams.TextHan.String= sprintf( ...
                        'CF = %.1f kHz | Thresh= %.1f dB SPL | Q_1_0^{global}= %.1f | Q_1_0^{local}= %.1f | TTR = %.1f dB', ...
                        app.TCparams.upd.CF_kHz, app.TCparams.upd.thresh_dB, app.TCparams.upd.Q10global, app.TCparams.upd.Q10local, ...
                        app.TCparams.upd.TTR_dB);
                    
                case {'Manual'}
                    app.TCparams.man.frhi= max(TCdata(:,1));
                    app.TCparams.man.Q10global= app.TCparams.man.CF_kHz/(app.TCparams.man.frhi-app.TCparams.man.frlo_global);
                    app.TCparams.TextHan.String= sprintf( ...
                        'CF = %.1f kHz | Thresh= %.1f dB SPL | Q_1_0^{global}= %.1f | Q_1_0^{local}= %.1f | TTR = %.1f dB', ...
                        app.TCparams.man.CF_kHz, app.TCparams.man.thresh_dB, app.TCparams.man.Q10global, app.TCparams.man.Q10local, ...
                        app.TCparams.man.TTR_dB);
                    
            end
            
        end

        % Button pushed function: NaNButton
        function NaNButtonPushed(app, event)
            
            switch app.CFThreshButtonGroup.SelectedObject.Text
                
                case {'Original'}
                    app.TCparams.org.frlo_global= nan;
                    app.TCparams.org.frhi= nan;
                    app.TCparams.org.Q10global= nan;
                    app.TCparams.TextHan.String= sprintf( ...
                        'CF = %.1f kHz | Thresh= %.1f dB SPL | Q_1_0^{global}= %.1f | Q_1_0^{local}= %.1f | TTR = %.1f dB', ...
                        app.TCparams.org.CF_kHz, app.TCparams.org.thresh_dB, app.TCparams.org.Q10global, app.TCparams.org.Q10local, ...
                        app.TCparams.org.TTR_dB);
                    
                case {'Updated'}
                    app.TCparams.upd.frlo_global= nan;
                    app.TCparams.upd.frhi= nan;
                    app.TCparams.upd.Q10global= nan;
                    app.TCparams.TextHan.String= sprintf( ...
                        'CF = %.1f kHz | Thresh= %.1f dB SPL | Q_1_0^{global}= %.1f | Q_1_0^{local}= %.1f | TTR = %.1f dB', ...
                        app.TCparams.upd.CF_kHz, app.TCparams.upd.thresh_dB, app.TCparams.upd.Q10global, app.TCparams.upd.Q10local, ...
                        app.TCparams.upd.TTR_dB);
                    
                case {'Manual'}
                    app.TCparams.man.frlo_global= nan;
                    app.TCparams.man.frhi= nan;
                    app.TCparams.man.Q10global= nan;
                    app.TCparams.TextHan.String= sprintf( ...
                        'CF = %.1f kHz | Thresh= %.1f dB SPL | Q_1_0^{global}= %.1f | Q_1_0^{local}= %.1f | TTR = %.1f dB', ...
                        app.TCparams.man.CF_kHz, app.TCparams.man.thresh_dB, app.TCparams.man.Q10global, app.TCparams.man.Q10local, ...
                        app.TCparams.man.TTR_dB);
                    
            end
            
        end

        % Button pushed function: NaNTTRButton
        function NaNTTRButtonPushed(app, event)
            switch app.CFThreshButtonGroup.SelectedObject.Text
                
                case {'Original'}
                    app.TCparams.org.TTR_dB= nan;
                    app.TCparams.org.Tail_Freq= nan;
                    app.TCparams.org.Tail_dB= nan;
                    app.TCparams.TextHan.String= sprintf( ...
                        'CF = %.1f kHz | Thresh= %.1f dB SPL | Q_1_0^{global}= %.1f | Q_1_0^{local}= %.1f | TTR = %.1f dB', ...
                        app.TCparams.org.CF_kHz, app.TCparams.org.thresh_dB, app.TCparams.org.Q10global, app.TCparams.org.Q10local, ...
                        app.TCparams.org.TTR_dB);
                    
                case {'Updated'}
                    app.TCparams.upd.TTR_dB= nan;
                    app.TCparams.upd.Tail_Freq= nan;
                    app.TCparams.upd.Tail_dB= nan;
                    app.TCparams.TextHan.String= sprintf( ...
                        'CF = %.1f kHz | Thresh= %.1f dB SPL | Q_1_0^{global}= %.1f | Q_1_0^{local}= %.1f | TTR = %.1f dB', ...
                        app.TCparams.upd.CF_kHz, app.TCparams.upd.thresh_dB, app.TCparams.upd.Q10global, app.TCparams.upd.Q10local, ...
                        app.TCparams.upd.TTR_dB);
                    
                case {'Manual'}
                    app.TCparams.man.TTR_dB= nan;
                    app.TCparams.man.Tail_Freq= nan;
                    app.TCparams.man.Tail_dB= nan;
                    app.TCparams.TextHan.String= sprintf('CF = %.1f kHz | Thresh= %.1f dB SPL | Q_1_0= %.1f | TTR = %.1f dB', ...
                        app.TCparams.man.CF_kHz, app.TCparams.man.thresh_dB, app.TCparams.man.Q10global, app.TCparams.man.TTR_dB);
                    
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Tuning Curve')
            xlabel(app.UIAxes, 'Frequency (kHz)')
            ylabel(app.UIAxes, 'Threshold (dB SPL)')
            app.INDataDirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.INDataDirectoryEditFieldLabel.Text = 'IN Data Directory';
            app.INDataDirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.INDataDirectoryEditField.Editable = 'off';
            app.ANFIdentifiersPanel = uipanel(app.UIFigure);
            app.ANFIdentifiersPanel.Title = 'ANF Identifiers';
            app.ChinIDEditFieldLabel = uilabel(app.ANFIdentifiersPanel);
            app.ChinIDEditFieldLabel.Text = 'Chin ID';
            app.ChinIDEditField = uieditfield(app.ANFIdentifiersPanel, 'numeric');
            app.ChinIDEditField.ValueChangedFcn = createCallbackFcn(app, @ChinIDEditFieldValueChanged, true);
            app.TrackEditFieldLabel = uilabel(app.ANFIdentifiersPanel);
            app.TrackEditFieldLabel.Text = 'Track';
            app.TrackEditField = uieditfield(app.ANFIdentifiersPanel, 'numeric');
            app.UnitEditFieldLabel = uilabel(app.ANFIdentifiersPanel);
            app.UnitEditFieldLabel.Text = 'Unit';
            app.UnitEditField = uieditfield(app.ANFIdentifiersPanel, 'numeric');
            app.PrevButton = uibutton(app.ANFIdentifiersPanel, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.PrevButton.Text = 'Prev';
            app.NextButton = uibutton(app.ANFIdentifiersPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PrintButton = uibutton(app.ANFIdentifiersPanel, 'push');
            app.PrintButton.ButtonPushedFcn = createCallbackFcn(app, @PrintButtonPushed, true);
            app.PrintButton.Text = 'Print';
            app.LoadTCButton = uibutton(app.ANFIdentifiersPanel, 'push');
            app.LoadTCButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTCButtonPushed, true);
            app.LoadTCButton.Text = 'LoadTC';
            app.Q10globalPanel = uipanel(app.UIFigure);
            app.Q10globalPanel.Title = 'Q10 (global)';
            app.ToLowestButton = uibutton(app.Q10globalPanel, 'push');
            app.ToLowestButton.ButtonPushedFcn = createCallbackFcn(app, @ToLowestButtonPushed, true);
            app.ToLowestButton.Text = 'To Lowest';
            app.ToHighestButton = uibutton(app.Q10globalPanel, 'push');
            app.ToHighestButton.ButtonPushedFcn = createCallbackFcn(app, @ToHighestButtonPushed, true);
            app.ToHighestButton.Text = 'To Highest';
            app.NaNButton = uibutton(app.Q10globalPanel, 'push');
            app.NaNButton.ButtonPushedFcn = createCallbackFcn(app, @NaNButtonPushed, true);
            app.NaNButton.Text = 'NaN';
            app.CLOSEButton = uibutton(app.UIFigure, 'push');
            app.CLOSEButton.ButtonPushedFcn = createCallbackFcn(app, @CLOSEButtonPushed, true);
            app.CLOSEButton.Text = 'CLOSE';
            app.STATUSTextAreaLabel = uilabel(app.UIFigure);
            app.STATUSTextAreaLabel.Text = 'STATUS';
            app.STATUSTextArea = uitextarea(app.UIFigure);
            app.STATUSTextArea.Value = {'Update Chin ID to start'};
            app.CFThreshButtonGroup = uibuttongroup(app.UIFigure);
            app.CFThreshButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CFThreshButtonGroupSelectionChanged, true);
            app.CFThreshButtonGroup.Title = 'CF&Thresh.';
            app.OriginalButton = uiradiobutton(app.CFThreshButtonGroup);
            app.OriginalButton.Text = 'Original';
            app.OriginalButton.Value = true;
            app.UpdatedButton = uiradiobutton(app.CFThreshButtonGroup);
            app.UpdatedButton.Text = 'Updated';
            app.ManualButton = uiradiobutton(app.CFThreshButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.DeleteButton = uiradiobutton(app.CFThreshButtonGroup);
            app.DeleteButton.Text = 'Delete';
            app.XYlimkHzdBPanel = uipanel(app.UIFigure);
            app.XYlimkHzdBPanel.Title = 'XYlim (kHz / dB)';
            app.ResetButton = uibutton(app.XYlimkHzdBPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset ';
            app.Xmin_EditField = uieditfield(app.XYlimkHzdBPanel, 'numeric');
            app.Xmin_EditField.ValueChangedFcn = createCallbackFcn(app, @Xmin_EditFieldValueChanged, true);
            app.Xmax_EditField = uieditfield(app.XYlimkHzdBPanel, 'numeric');
            app.Xmax_EditField.ValueChangedFcn = createCallbackFcn(app, @Xmax_EditFieldValueChanged, true);
            app.Ymax_EditField = uieditfield(app.XYlimkHzdBPanel, 'numeric');
            app.Ymax_EditField.ValueChangedFcn = createCallbackFcn(app, @Ymax_EditFieldValueChanged, true);
            app.Ymin_EditField = uieditfield(app.XYlimkHzdBPanel, 'numeric');
            app.Ymin_EditField.ValueChangedFcn = createCallbackFcn(app, @Ymin_EditFieldValueChanged, true);
            app.DynRangeButton = uibutton(app.XYlimkHzdBPanel, 'push');
            app.DynRangeButton.ButtonPushedFcn = createCallbackFcn(app, @DynRangeButtonPushed, true);
            app.DynRangeButton.Text = 'DynRange';
            app.NaNTTRButton = uibutton(app.UIFigure, 'push');
            app.NaNTTRButton.ButtonPushedFcn = createCallbackFcn(app, @NaNTTRButtonPushed, true);
            app.NaNTTRButton.Text = 'NaN:TTR';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FTCscreening

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end