classdef disable_clicks_when_using_toolbar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % plot some data
            x = 1:5; y = 1:5;
            p = plot(app.UIAxes,x,y,'ButtonDownFcn',@line_click);
            
            % set up callback for toolbar
            app.UIAxes.Toolbar = axtoolbar(app.UIAxes,{'zoomin','zoomout','pan','datacursor'},...
                                           'SelectionChangedFcn',{@tbar_change,p});
            
            % define callbacks as nested functions
            
            function line_click(~,~)
                disp('click!');
            end
            
            function tbar_change(~,event,p)
                state = event.Selection.Value; % ToolBarStateButton has a "Value of "on" or "off"
                
                % if selected, turn off line_click callback
                if state == 'on'
                   disp('toolbar button selected; callback turned off');
                   p.ButtonDownFcn = [];
                   
                % if not selected, turn back on line_click
                else
                   disp('toolbar button de-selected; callback turned back on');
                   p.ButtonDownFcn = @line_click;
                end
                
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = disable_clicks_when_using_toolbar

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end